classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SourceImagesPathLabel          matlab.ui.control.Label
        SourceImagesPathEditField      matlab.ui.control.EditField
        MosaicGeneratorLabel           matlab.ui.control.Label
        TargetimagepathEditFieldLabel  matlab.ui.control.Label
        TargetimagepathEditField       matlab.ui.control.EditField
        TilesEditFieldLabel            matlab.ui.control.Label
        TilesEditField                 matlab.ui.control.EditField
        GenerateMosaicButton           matlab.ui.control.Button
        HeightEditFieldLabel           matlab.ui.control.Label
        HeightEditField                matlab.ui.control.EditField
        WidthEditField_2Label          matlab.ui.control.Label
        WidthEditField_2               matlab.ui.control.EditField
        TilesEditFieldLabel_2          matlab.ui.control.Label
        ResultimagepathEditFieldLabel  matlab.ui.control.Label
        ResultimagepathEditField       matlab.ui.control.EditField
        SelectfolderButton             matlab.ui.control.Button
        ChooseimageButton              matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        ResultImageLabel               matlab.ui.control.Label
        UIAxes_2                       matlab.ui.control.UIAxes
        TargetImageLabel               matlab.ui.control.Label
        SelectfolderButton_2           matlab.ui.control.Button
        TargetimagewillbedisplayedhereLabel  matlab.ui.control.Label
        MosaicimagewillbedisplayedhereLabel  matlab.ui.control.Label
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             clc
%             clear all
        end

        % Callback function
        function GenerateMosaicButtonValueChanged(app, event)
            
        end

        % Button pushed function: GenerateMosaicButton
        function GenerateMosaicButtonPushed(app, event)
            sourcePath = app.SourceImagesPathEditField.Value;
            targetImage = imread(app.TargetimagepathEditField.Value);
            tiles = app.TilesEditField.Value;
            width = app.WidthEditField_2.Value;
            height = app.HeightEditField.Value;
            result = app.ResultimagepathEditField.Value;
            main(sourcePath, targetImage, width, height, tiles, result);
            app.UIAxes.Visible = 'on';
            I = imresize(imread(result), [160 240]);
            app.MosaicimagewillbedisplayedhereLabel.Visible = 'off';
            imshow(I, 'Parent', app.UIAxes)
            
            f = app.UIFigure;
            message = sprintf('Success! \n The mosaic image was generated. \n The image was save under the folder you selected and named "result.jpg"');
            uialert(f,message,'Success',...
            'Icon','success');
        end

        % Button pushed function: SelectfolderButton
        function SelectfolderButtonPushed(app, event)
            folder = uigetdir();
            app.SourceImagesPathEditField.Value = folder;
        end

        % Button pushed function: ChooseimageButton
        function ChooseimageButtonPushed(app, event)
            [file, path] = uigetfile('*.jpg');
            app.TargetimagepathEditField.Value = path + "" + file;
            I = imresize(imread(path + "" + file), [160 240]);
            app.TargetimagewillbedisplayedhereLabel.Visible = 'off';
            imshow(I, 'Parent', app.UIAxes_2)
        end

        % Button pushed function: SelectfolderButton_2
        function SelectfolderButton_2Pushed(app, event)
            folder = uigetdir();
            app.ResultimagepathEditField.Value = folder + "\result.jpg";
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SourceImagesPathLabel = uilabel(app.UIFigure);
            app.SourceImagesPathLabel.Text = 'Source Images Path';
            app.SourceImagesPathEditField = uieditfield(app.UIFigure, 'text');
            app.MosaicGeneratorLabel = uilabel(app.UIFigure);
            app.MosaicGeneratorLabel.Text = 'Mosaic Generator';
            app.TargetimagepathEditFieldLabel = uilabel(app.UIFigure);
            app.TargetimagepathEditFieldLabel.Text = 'Target image path';
            app.TargetimagepathEditField = uieditfield(app.UIFigure, 'text');
            app.TilesEditFieldLabel = uilabel(app.UIFigure);
            app.TilesEditFieldLabel.Text = 'Tiles';
            app.TilesEditField = uieditfield(app.UIFigure, 'text');
            app.GenerateMosaicButton = uibutton(app.UIFigure, 'push');
            app.GenerateMosaicButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateMosaicButtonPushed, true);
            app.GenerateMosaicButton.Text = 'Generate Mosaic';
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'text');
            app.WidthEditField_2Label = uilabel(app.UIFigure);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.UIFigure, 'text');
            app.TilesEditFieldLabel_2 = uilabel(app.UIFigure);
            app.TilesEditFieldLabel_2.Text = 'Target image size';
            app.ResultimagepathEditFieldLabel = uilabel(app.UIFigure);
            app.ResultimagepathEditFieldLabel.Text = 'Result image path';
            app.ResultimagepathEditField = uieditfield(app.UIFigure, 'text');
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButtonPushed, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.ChooseimageButton = uibutton(app.UIFigure, 'push');
            app.ChooseimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseimageButtonPushed, true);
            app.ChooseimageButton.Text = 'Choose image';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.SelectfolderButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectfolderButton_2Pushed, true);
            app.SelectfolderButton_2.Text = 'Select folder';
            app.ResultImageLabel = uilabel(app.UIFigure);
            app.ResultImageLabel.Text = 'Result Image';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.TargetImageLabel = uilabel(app.UIFigure);
            app.TargetImageLabel.Text = 'Target Image';
            app.TargetimagewillbedisplayedhereLabel = uilabel(app.UIFigure);
            app.TargetimagewillbedisplayedhereLabel.Text = 'Target image will be displayed here';
            app.MosaicimagewillbedisplayedhereLabel = uilabel(app.UIFigure);
            app.MosaicimagewillbedisplayedhereLabel.Text = 'Mosaic image will be displayed here';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end